classdef video_preobraz < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        limitEditFieldLabel       matlab.ui.control.Label
        limitEditField            matlab.ui.control.NumericEditField
        file_pathEditFieldLabel   matlab.ui.control.Label
        file_pathEditField        matlab.ui.control.EditField
        breakButton               matlab.ui.control.StateButton
        Label_2                   matlab.ui.control.Label
        Lamp                      matlab.ui.control.Lamp
        executeButton             matlab.ui.control.StateButton
        execute_preprocessButton  matlab.ui.control.StateButton
        Label                     matlab.ui.control.Label
        saveButton                matlab.ui.control.StateButton
        noneLabel                 matlab.ui.control.Label
        Label_3                   matlab.ui.control.Label
        UIAxes                    matlab.ui.control.UIAxes
        UIAxes2                   matlab.ui.control.UIAxes
        UIAxes2_2                 matlab.ui.control.UIAxes
        UIAxes2_3                 matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        Property % Description
        frames_gray
        fon_blue
        fon_green
        areas
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: execute_preprocessButton
        function execute_preprocessButtonValueChanged(app, event)
                app.execute_preprocessButton.Enable = 'off';                 
                video = VideoReader(app.file_pathEditField.Value);
                % Свойства видео:
                width = video.Width;
                height = video.Height;
                frameRate = video.FrameRate;
                numOfFrames = video.NumberOfFrames;
                % *Далее все константы, которые надо поменять вручную до запуска программы:                    
                cut_start=1; %писать вручную, сколько убрать кадров в начале  
                cut_end=numOfFrames; %писать вручную, сколько убрать кадров в конце                    
                cropsize=[0, 0, width, height]; %обрезать кадры, указать [xmin ymin width height]
                % левый верхний угол образца - это xmin, ymin                    
                app.frames_gray = cell(1,cut_end - cut_start);%numOfFrames-(cut_start+cut_end)); %пустой массив, чтобы быстрее работал цикл
                tic
                for i=1:cut_end - cut_start%numOfFrames-(cut_start+cut_end) %50000 кадров (30 минут видео 25 кадров в секунду) будет обрабатывать 10 минут, то есть 100 кадров за 1 секунду
                    k=i+cut_start; %убирает первые кадры
                    app.Label.Text =num2str(round(i/(cut_end-cut_start),2));
                    drawnow;
                    frames_crop=imcrop(read(video, k),cropsize); %обрезка кадров
                    app.fon_green{i} = imbinarize(frames_crop(:,:,2)-frames_crop(:,:,1));%находит зеленый фон
                    app.frames_gray{i} = imadjust(rgb2gray(frames_crop)); %делает черно-белые вместо rgb                             
                end
                app.Lamp.Enable = 'on';
        end

        % Value changed function: executeButton
        function executeButtonValueChanged(app, event)
                app.executeButton.Enable = 'off'; 
                app.areas = [];
                app.Label_3.Text ='';
                por = app.limitEditField.Value;
                for i=1:length(app.frames_gray)%length(frames_gray)                        
                    if app.breakButton.Value
                       app.breakButton.Value = false;
                       app.executeButton.Enable = 'on';
                       app.executeButton.Value = false;
                       app.Label_3.Text ='finished';
                       break;
                     end
                     app.noneLabel.Text =int2str(i);
                     drawnow;
                     arr = [];
                     arr1 = [];
                     drawnow;
                     imshow(app.frames_gray{i},'Parent',app.UIAxes2)                      
                     title(num2str(i));
                     h=fspecial('laplacian', .3);%элемент для ФВЧ                 
                     BWdfill = ~app.fon_green{i};
                     
                     feats = regionprops(BWdfill,'BoundingBox');
                     S = regionprops(BWdfill,'Area');
                     arr = zeros(1,length(S));
                     for j = 1 : length(S)
                         arr(j) =  S(j).Area;
                     end
                     k = find(arr == max(arr));%поиск элемента максимальной площади 
                     cropsize_new = feats(k).BoundingBox;
                     imshow(BWdfill,'Parent',app.UIAxes2_3);
                     %rectangle('Position', feats(k).BoundingBox,'EdgeColor','r');
                     app.frames_gray{i}  = app.frames_gray{i}.*uint8(~app.fon_green{i});
                     plate = imcrop(app.frames_gray{i},cropsize_new);%обрезание начального изображения
                         
                     ID = plate;
                     circ_se = strel('disk',3); %элемент для дилатации и эрозии
                     ID1= imbinarize(ID,0.15); %вся резина
                     ID2 = imbinarize(ID,por); %резина без воды
                     ID11 = ID1-ID2;%вода без резины
                     ID11=bwareaopen(ID11,10000);% удаление малых объектов
                     ID11 = imerode(ID11,circ_se);%эрозия
                     ID11=imfill(ID11, 'holes');%заполнение дыр
                     bord = imfilter(ID11,h,'replicate');%нахождение границы жидкости
                     bord = uint8(imdilate(bord,circ_se));% расширение линии границы
                     bb = (256*bord+ID); % изображение с отмеченной границей
                     imshow(bb,'Parent',app.UIAxes2_2); 
                     feats1 = regionprops(ID11,'BoundingBox');
                     S1 = regionprops(ID11,'Area');
                     if length(S1) == 0
                        app.areas(i) = 0;
                     else %нахождение объекта максимальной площади
                        arr1 = zeros(1,length(S1));
                        for j = 1 : length(S1)
                            arr1(j) =  S1(j).Area;
                        end
                        m = find(arr1 == max(arr1));
                        app.areas(i) = S1(m).Area/S(k).Area;
                        rectangle('Position', feats1(m).BoundingBox,'EdgeColor','r','Parent',app.UIAxes2_2);
                        title(num2str(app.areas(i)));
                     end
                     plot(app.UIAxes,app.areas);
                          
                end
                app.Label_3.Text ='finished';
            
        end

        % Value changed function: breakButton
        function breakButtonValueChanged(app, event)
        app.breakButton.Value = true;   
        end

        % Value changed function: saveButton
        function saveButtonValueChanged(app, event)
            assignin("base",'percentage',app.areas)
            assignin("base",'picts',app.frames_gray)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.limitEditFieldLabel = uilabel(app.UIFigure);
            app.limitEditFieldLabel.Text = 'limit';
            app.limitEditField = uieditfield(app.UIFigure, 'numeric');
            app.limitEditField.Limits = [0 1];
            app.limitEditField.Value = 0.65;
            app.file_pathEditFieldLabel = uilabel(app.UIFigure);
            app.file_pathEditFieldLabel.Text = 'file_path';
            app.file_pathEditField = uieditfield(app.UIFigure, 'text');
            app.file_pathEditField.Value = 'C:\ВУЗ дополнительно\5 семестр\Эксперимент с чернилами\01.12.2021\VID_20211201_163337.mp4';
            app.breakButton = uibutton(app.UIFigure, 'state');
            app.breakButton.ValueChangedFcn = createCallbackFcn(app, @breakButtonValueChanged, true);
            app.breakButton.Text = 'break';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '100%';
            app.Lamp = uilamp(app.UIFigure);
            app.Lamp.Enable = 'off';
            app.executeButton = uibutton(app.UIFigure, 'state');
            app.executeButton.ValueChangedFcn = createCallbackFcn(app, @executeButtonValueChanged, true);
            app.executeButton.Text = 'execute';
            app.execute_preprocessButton = uibutton(app.UIFigure, 'state');
            app.execute_preprocessButton.ValueChangedFcn = createCallbackFcn(app, @execute_preprocessButtonValueChanged, true);
            app.execute_preprocessButton.Text = 'execute_preprocess';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '0 %';
            app.saveButton = uibutton(app.UIFigure, 'state');
            app.saveButton.ValueChangedFcn = createCallbackFcn(app, @saveButtonValueChanged, true);
            app.saveButton.Text = {'save '; ''};
            app.noneLabel = uilabel(app.UIFigure);
            app.noneLabel.Text = {'none'; ''; ''};
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = '';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'water runoff')
            xlabel(app.UIAxes, 'frame number')
            ylabel(app.UIAxes, {'underwater percentage'; ''})
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes2_2 = uiaxes(app.UIFigure);
            zlabel(app.UIAxes2_2, 'Z')
            app.UIAxes2_3 = uiaxes(app.UIFigure);
            title(app.UIAxes2_3, 'Title')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = video_preobraz

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end